classdef img_overlay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImgOverlay                matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Img1Button                matlab.ui.control.Button
        Img2Button                matlab.ui.control.Button
        ControlPanel              matlab.ui.container.Panel
        ScalingSpinnerLabel       matlab.ui.control.Label
        ScalingSpinner            matlab.ui.control.Spinner
        VerticalSpinnerLabel      matlab.ui.control.Label
        VerticalSpinner           matlab.ui.control.Spinner
        HorizontalSpinnerLabel    matlab.ui.control.Label
        HorizontalSpinner         matlab.ui.control.Spinner
        RotationdegreeKnobLabel   matlab.ui.control.Label
        RotationdegreeKnob        matlab.ui.control.Knob
        Slider                    matlab.ui.control.Slider
        Switch                    matlab.ui.control.Switch
        TransparencyLabel         matlab.ui.control.Label
        Step1LoadImagefilesLabel  matlab.ui.control.Label
        Step2AdjuctLabel          matlab.ui.control.Label
        Step3ExportLabel          matlab.ui.control.Label
        ExportButton              matlab.ui.control.Button
        MovingLabel               matlab.ui.control.Label
        FixedLabel                matlab.ui.control.Label
        ImageOverlayV10Label      matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        ImagesPanel               matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        img1 %moving image
        img2 %fixed image
        img1_1 %moving image
        img1_2 %moving image
        img1_out %moving image
        
        img2_1 %fixed image
        img2_2 %fixed image
        img2_out %fixed image
    end
    
    methods (Access = private)
        
        function  update(app)
         if ~isempty(app.img1) && ~isempty(app.img2)
            app.ControlPanel.Visible='on';
            scale=app.ScalingSpinner.Value;
            offsetY=app.VerticalSpinner.Value;
            offsetX=app.HorizontalSpinner.Value;
            transparency=app.Slider.Value;
            angle=app.RotationdegreeKnob.Value;
            
            app.img1_1=imresize(app.img1,scale);
            [s1,s2,~]=size(app.img1_1);
            
            app.img1_2 = uint8(ones(s1+offsetY,s2+offsetX,3));
            for i=1:3
              app.img1_2(:,:,i) = app.img1_2(:,:,i) *app.img1_1(end,end,i);
            end           
            
            if offsetX>=0 && offsetY>=0
              app.img1_2 (offsetY+1:offsetY+s1,offsetX+1:offsetX+s2,:) = app.img1_1(1:s1,1:s2,:);
            elseif offsetX<=0 && offsetY<=0
              app.img1_2 (1:s1+offsetY,1:s2+offsetX,:) = app.img1_1(1-offsetY:s1,1-offsetX:s2,:);
            elseif offsetX>=0 && offsetY<=0
              app.img1_2 (1:s1+offsetY,offsetX+1:offsetX+s2,:) = app.img1_1(1-offsetY:s1,1:s2,:);   
            elseif offsetX<=0 && offsetY>=0
              app.img1_2 (offsetY+1:offsetY+s1,1:s2+offsetX,:) = app.img1_1(1:s1,1-offsetX:s2,:);  
            end
            
            %app.img1_2=padarray(app.img1_1,[offsetY,offsetX],'replicate','pre');
            
            app.img1_out= imrotate(app.img1_2,angle);
            
            app.img2_out= app.img2; 
            
            %imshowpair(app.img1_out,app.img2_out,'blend','parent',app.UIAxes)
            
            
            if strcmp(app.Switch.Value,'img1')
                A=app.img2_out;
                B=app.img1_out;
            else
                A=app.img1_out;
                B=app.img2_out;
            end
            
            
            tic
            hold (app.UIAxes,'off')
            imshow(A,'parent',app.UIAxes)
            hold (app.UIAxes,'on')
            imshow(B,'parent',app.UIAxes)
            toc
            alpha(app.UIAxes,transparency)
            
            
         end
        end
        
        function default(app)
           if ~isempty(app.img1) && ~isempty(app.img2)
               [s1,s2,~]=size(app.img1);
               [f1,f2,~]=size(app.img2);
               scaling=min(f1/s1,f2/s2);
               app.ScalingSpinner.Value=scaling; 
               app.ScalingSpinner.Step=scaling/200; 
               app.VerticalSpinner.Value=0;
               app.VerticalSpinner.Step=ceil((f1+f2)/1000);
               app.HorizontalSpinner.Value=0;
               app.HorizontalSpinner.Step=ceil((f1+f2)/1000);
               app.UIAxes.Visible='on';
           end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ControlPanel.Visible='off';
            %app.UIAxes1.Visible='off';
            %app.UIAxes2.Visible='off';
            app.UIAxes.Visible='off';
            %app.UIAxes1.XLabel.String=[];app.UIAxes1.YLabel.String=[];
            %app.UIAxes2.XLabel.String=[];app.UIAxes2.YLabel.String=[];
            app.UIAxes.XLabel.String=[];app.UIAxes.YLabel.String=[];
            
            %app.UIAxes1.Title.String=[];
            %app.UIAxes2.Title.String=[];
            app.UIAxes.Title.String=[];
            
            %app.UIAxes1.XTickLabel=[];app.UIAxes1.YTickLabel=[];
            %app.UIAxes2.XTickLabel=[];app.UIAxes2.YTickLabel=[];
            app.UIAxes.XTickLabel=[];app.UIAxes.YTickLabel=[];
            
            %app.UIAxes1.XTick=[];app.UIAxes1.YTick=[];
            %app.UIAxes2.XTick=[];app.UIAxes2.YTick=[];
            app.UIAxes.XTick=[];app.UIAxes.YTick=[];
            
        end

        % Value changed function: VerticalSpinner
        function VerticalSpinnerValueChanged(app, event)
            value = app.VerticalSpinner.Value;
            app.update();
        end

        % Value changed function: HorizontalSpinner
        function HorizontalSpinnerValueChanged(app, event)
            value = app.HorizontalSpinner.Value;
             app.update();
        end

        % Value changed function: ScalingSpinner
        function ScalingSpinnerValueChanged(app, event)
            value = app.ScalingSpinner.Value;
            app.update();
        end

        % Value changed function: RotationdegreeKnob
        function RotationdegreeKnobValueChanged(app, event)
            value = app.RotationdegreeKnob.Value;
            app.update();
        end

        % Button pushed function: Img1Button
        function Img1_smallButtonPushed(app, event)
            [filename,pathname]=uigetfile('*.jpg;*.png;*.tiff;*.jpge');
            fname=fullfile(pathname,filename);
            if ~filename==0
               app.img1=imread(fname);
               figure,imshow(fname);
             
              % app.Image1.ImageSource=fname;
               default(app)
               update(app)
            end            
        end

        % Button pushed function: Img2Button
        function Img2_bigButtonPushed(app, event)
             [filename,pathname]=uigetfile('*.jpg;*.png;*.tiff;*.jpge');
            fname=fullfile(pathname,filename);
             if ~filename==0
               app.img2=imread(fname);
                 figure,imshow(fname)

                %app.Image2.ImageSource=fname;
               default(app)
               update(app)
             end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.update();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
             app.update();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %   f=figure;
            f=imshowpair(app.img1_out,app.img2_out,'blend','parent',app.UIAxes)
           % f=getimage(app.UIAxes)
            imsave(f)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ImgOverlay.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {798, 798};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {246, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImgOverlay = uifigure('Visible', 'off');
            app.ImgOverlay.Name = 'ImgOverlay';
            app.ImgOverlay.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ImgOverlay);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Img1Button = uibutton(app.LeftPanel, 'push');
            app.Img1Button.ButtonPushedFcn = createCallbackFcn(app, @Img1_smallButtonPushed, true);
            app.Img1Button.Text = 'Img1';
            app.Img2Button = uibutton(app.LeftPanel, 'push');
            app.Img2Button.ButtonPushedFcn = createCallbackFcn(app, @Img2_bigButtonPushed, true);
            app.Img2Button.Text = 'Img2';
            app.ControlPanel = uipanel(app.LeftPanel);
            app.ControlPanel.Title = 'Control Panel';
            app.ScalingSpinnerLabel = uilabel(app.ControlPanel);
            app.ScalingSpinnerLabel.Text = 'Scaling';
            app.ScalingSpinner = uispinner(app.ControlPanel);
            app.ScalingSpinner.Step = 0.05;
            app.ScalingSpinner.ValueChangedFcn = createCallbackFcn(app, @ScalingSpinnerValueChanged, true);
            app.ScalingSpinner.Value = 6.8;
            app.VerticalSpinnerLabel = uilabel(app.ControlPanel);
            app.VerticalSpinnerLabel.Text = 'Vertical';
            app.VerticalSpinner = uispinner(app.ControlPanel);
            app.VerticalSpinner.Step = 5;
            app.VerticalSpinner.ValueDisplayFormat = '%.0f';
            app.VerticalSpinner.ValueChangedFcn = createCallbackFcn(app, @VerticalSpinnerValueChanged, true);
            app.HorizontalSpinnerLabel = uilabel(app.ControlPanel);
            app.HorizontalSpinnerLabel.Text = 'Horizontal';
            app.HorizontalSpinner = uispinner(app.ControlPanel);
            app.HorizontalSpinner.Step = 5;
            app.HorizontalSpinner.ValueDisplayFormat = '%.0f';
            app.HorizontalSpinner.ValueChangedFcn = createCallbackFcn(app, @HorizontalSpinnerValueChanged, true);
            app.RotationdegreeKnobLabel = uilabel(app.ControlPanel);
            app.RotationdegreeKnobLabel.Text = 'Rotation (degree)';
            app.RotationdegreeKnob = uiknob(app.ControlPanel, 'continuous');
            app.RotationdegreeKnob.Limits = [-5 5];
            app.RotationdegreeKnob.ValueChangedFcn = createCallbackFcn(app, @RotationdegreeKnobValueChanged, true);
            app.Slider = uislider(app.ControlPanel);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.5;
            app.Switch = uiswitch(app.ControlPanel, 'slider');
            app.Switch.Items = {'img1', 'img2'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'img1';
            app.TransparencyLabel = uilabel(app.ControlPanel);
            app.TransparencyLabel.Text = 'Transparency';
            app.Step1LoadImagefilesLabel = uilabel(app.LeftPanel);
            app.Step1LoadImagefilesLabel.Text = 'Step 1. Load Image files';
            app.Step2AdjuctLabel = uilabel(app.LeftPanel);
            app.Step2AdjuctLabel.Text = 'Step 2. Adjuct ';
            app.Step3ExportLabel = uilabel(app.LeftPanel);
            app.Step3ExportLabel.Text = 'Step 3. Export ';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.MovingLabel = uilabel(app.LeftPanel);
            app.MovingLabel.Text = 'Moving';
            app.FixedLabel = uilabel(app.LeftPanel);
            app.FixedLabel.Text = 'Fixed';
            app.ImageOverlayV10Label = uilabel(app.LeftPanel);
            app.ImageOverlayV10Label.Text = 'Image Overlay  V1.0';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImagesPanel = uipanel(app.RightPanel);
            app.ImagesPanel.Title = 'Images Panel';
            app.GridLayout2 = uigridlayout(app.ImagesPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.ImgOverlay.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = img_overlay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImgOverlay)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImgOverlay)
        end
    end
end